classdef DataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SepbyEditField                  matlab.ui.control.NumericEditField
        SepbyLabel                      matlab.ui.control.Label
        SynchronizationPanel            matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        StartupTab                      matlab.ui.container.Tab
        PFIchannelLabel                 matlab.ui.control.Label
        ClockingTab                     matlab.ui.container.Tab
        PFIchannelLabel_2               matlab.ui.control.Label
        SyncDAQsviaclockfromamasterDAQLabel  matlab.ui.control.Label
        DoClockingCheckBox              matlab.ui.control.CheckBox
        CameraTab                       matlab.ui.container.Tab
        CameraRateHzEditField           matlab.ui.control.NumericEditField
        CameraRateHzEditFieldLabel      matlab.ui.control.Label
        ConfigFilePathEditField         matlab.ui.control.EditField
        ConfigFilePathEditFieldLabel    matlab.ui.control.Label
        UseCameraCheckBox               matlab.ui.control.CheckBox
        DAQsListBox                     matlab.ui.control.ListBox
        DAQsLabel                       matlab.ui.control.Label
        ArmDataAcquisitionPanel         matlab.ui.container.Panel
        ArmDataAcquisitionSwitch        matlab.ui.control.Switch
        TriggerControlsPanel            matlab.ui.container.Panel
        TrigModeSwitch                  matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        ExtTrigTab                      matlab.ui.container.Tab
        RiseOrFallSwitch                matlab.ui.control.Switch
        VoltagethresholdEditField       matlab.ui.control.NumericEditField
        VoltagethresholdEditFieldLabel  matlab.ui.control.Label
        SamplesposttriggerEditField     matlab.ui.control.NumericEditField
        SamplesposttriggerEditFieldLabel  matlab.ui.control.Label
        ClickTrigTab                    matlab.ui.container.Tab
        TriggerButton                   matlab.ui.control.StateButton
        RateTimeControlsPanel           matlab.ui.container.Panel
        ReadRateHzEditField             matlab.ui.control.NumericEditField
        ReadRateHzEditFieldLabel        matlab.ui.control.Label
        RecordTimesEditField            matlab.ui.control.NumericEditField
        RecordTimesEditFieldLabel       matlab.ui.control.Label
        PlotRateHzEditField             matlab.ui.control.NumericEditField
        PlotRateHzEditFieldLabel        matlab.ui.control.Label
        DAQRateHzEditField              matlab.ui.control.NumericEditField
        DAQRateHzEditFieldLabel         matlab.ui.control.Label
        FileSavingParametersPanel       matlab.ui.container.Panel
        FolderNote                      matlab.ui.control.Label
        FolderButton                    matlab.ui.control.Button
        ProjectFolderEditField          matlab.ui.control.EditField
        ProjectFolderEditFieldLabel     matlab.ui.control.Label
        FilenameoptionalEditField       matlab.ui.control.EditField
        FilenameoptionalEditFieldLabel  matlab.ui.control.Label
        mainPlot                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dn      % Names of recording DAQs (used to name fields for everything)
        d       % DAQ DataAcquisition object
        data    % Acquired data
        name    % Filenames and folders
        channels% Input channel info
        sync    % Synchronization controls and info
        rate    % Relevant information for timing/rates
        plt     % Relevant objects for plotting (plotTimer, colors, etc)
        channelPanels
        fieldsToSave = {'RecordTimesEditField', 'PlotRateHzEditField', ...
                'DAQRateHzEditField', 'ReadRateHzEditField', 'ProjectFolderEditField', ...
                'FilenameoptionalEditField', 'SepbyEditField', ...
                'DAQsListBox', 'DoClockingCheckBox', ...
                'TrigModeSwitch', 'RiseOrFallSwitch', 'VoltagethresholdEditField', 'SamplesposttriggerEditField', ...
                'ConfigFilePathEditField', 'UseCameraCheckBox', 'CameraRateHzEditField'};
        perChannelFieldsToSave = {'chanOn_', 'chanDisplay_', 'chanName_', 'toggleButton_'};
        syncFieldsToSave = {'PFInum', 'clockPFInum'}
        recordingDisableFields = {'ProjectFolderEditField', 'FolderButton', ...
            'FilenameoptionalEditField', 'DAQRateHzEditField', 'PlotRateHzEditField', ...
            'ReadRateHzEditField', 'RecordTimesEditField', 'DAQsListBox', 'DoClockingCheckBox', ...
            'ConfigFilePathEditField', 'UseCameraCheckBox', 'CameraRateHzEditField'};
        recordingDisableChannelFields = {'DAQNameField', 'terminalConfig', 'NumberofChannelsEditField', 'TrigButtonGroup'};
        recordingDisableSyncFields = {'PFInum', 'clockPFInum'};
        % TODO: Put Sync under channels to vastly simplify enable/disable
        % and load/save. Shorten channels to something 4 letter
    end

    methods (Access = private)

        % TODO: Combine GrabDataRise and GrabDataFall to single function
        % Function callback for when DAQ data available
        % Case: look for analog RISING trigger on trigger channel
        function GrabDataRise(app,obj,event)
            [newdata, timestamps, ~] = read(obj, obj.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            shiftpos = size(newdata, 1);
            app.data.time =  [app.data.time(shiftpos+1:end); timestamps];
            app.data.data = [app.data.data(shiftpos+1:end, :); newdata];
            % Check for trigger fall on master DAQ
            if newdata(end, app.sync.trigind) > app.VoltagethresholdEditField.Value
                stop(app.d)
                % Might need a wait here to run this function once stop occurs
                saveData(app)
                stopRecordingReset(app)
            end
        end

        % Function callback for when DAQ data available
        % Case: look for analog FALLING trigger on trigger channel
        function GrabDataFall(app,obj,event)
            [newdata, timestamps, ~] = read(obj, obj.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            shiftpos = size(newdata, 1);
            app.data.time =  [app.data.time(shiftpos+1:end); timestamps];
            app.data.data = [app.data.data(shiftpos+1:end, :); newdata];
            % Check for trigger fall on master DAQ
            if newdata(end, app.sync.trigind) < app.VoltagethresholdEditField.Value
                stop(app.d)
                % Might need a wait here to run this function once stop occurs
                saveData(app)
                stopRecordingReset(app)
            end
        end

        % Function callback for when DAQ data available
        % Case: Trigger button controlled, no analog trigger searching
        function GrabData(app,obj,event)
            [newdata, timestamps, ~] = read(obj, obj.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            shiftpos = size(newdata, 1);
            app.data.time = circshift(app.data.time, -shiftpos);
            app.data.data = circshift(app.data.data, -shiftpos);
            app.data.time(end-shiftpos+1:end,:) = timestamps;
            app.data.data(end-shiftpos+1:end,:) = newdata;
        end
        
        % Function callback repeatedly called by plot update timer
        function plotTimerUpdateFcn(app,src,event)
            offset = app.SepbyEditField.Value;
            for j = find(app.channels.active)
                % If channel set to plot
                if app.channels.display(j)
                    set(app.channels.(['line_',num2str(j)]), ...
                        'XData', app.data.fakeTime(1:size(app.data.data,1)), ...
                        'YData', app.data.data(:,app.channels.activeIndex(j)) + (app.channels.activeIndex(j)-1)*offset);
                else
                    set(app.channels.(['line_',num2str(j)]), 'XData', nan, 'YData', nan);
                end
            end
            drawnow limitrate
        end
    
        % For each channel, store some basic information for reference throughout
        % Note: active and display are boolean
        % All of these are defined as row vectors so matlab will actually iterate over them properly
        function getChannelInformation(app)
            % Get how many total channels
            app.channels.ntotal = 0;
            for dq = app.DAQsListBox.Value
                daq = dq{1};
                app.channels.ntotal = app.channels.ntotal + app.channels.(daq).howMany;
            end
            % Preallocate
            app.channels.devID = strings(1, app.channels.ntotal);
            app.channels.name = strings(1, app.channels.ntotal);
            app.channels.active = false(1, app.channels.ntotal);
            app.channels.display = false(1, app.channels.ntotal);
            app.channels.deviceIndex = zeros(1, app.channels.ntotal);
            % Loop over DAQ devices, fill in actual info for each channel
            totalIndex = 1;
            for dq = app.DAQsListBox.Value
                daq = dq{1};
                for chind = 1:app.channels.(daq).howMany
                    app.channels.devID(totalIndex) = daq;
                    app.channels.name(totalIndex) = app.channels.(daq).(['chanName_',num2str(chind)]).Value;
                    app.channels.active(totalIndex) = strcmp(app.channels.(daq).(['chanOn_',num2str(chind)]).Value, 'On');
                    app.channels.display(totalIndex) = app.channels.(daq).(['chanDisplay_',num2str(chind)]).Value;
                    app.channels.deviceIndex(totalIndex) = chind;
                    totalIndex = totalIndex + 1;
                end
            end
            % Useful for converting to indices for data acquired, as we only acquire from active channels
            app.channels.activeIndex = cumsum(app.channels.active);
        end

        function createChannelPanel(app, index, numInit)
            % Create panel 
            app.channelPanels.(app.dn{index}) = uipanel(app.UIFigure, ...
                'Title', [app.dn{index}, ' channels'], ...
                'Position', [45 + (215 + 10)*(index-1), 0, 215, 400], ...
                'Scrollable', 'on', ...
                'Tag', num2str(index));
            % Create DAQ name field
            app.channels.(app.dn{index}).DAQNameField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'text', ...
                'Position', [0,360,80,22], ...
                'Value', app.dn{index}, ...
                'ValueChangedFcn', createCallbackFcn(app, @DAQNameValueChangedFcn, true), ...
                'Tooltip', {'Name of this DAQ','Data will be saved under file with this name'});
            % Create Terminal config selection field
            app.channels.(app.dn{index}).terminalConfig = uidropdown(app.channelPanels.(app.dn{index}), ...
                'Items', {'Differential', 'SingleEnded', 'SingleEndedNonReferenced', 'PseudoDifferential'}, ...
                'Value', 'Differential', ...
                'Position', [100, 360, 100, 22]);
            % Create "Number of channels" numeric field
            app.channels.(app.dn{index}).NumberofChannelsEditField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'numeric', ...
                'Position', [135,335,31,22], ...
                'Value', numInit, ...
                'ValueChangedFcn', createCallbackFcn(app, @ChangeNumChannelsFcn, true));
            % Create trigger selection button group
            height = 25*numInit + 10 + 2*app.channels.trigOffset;
            app.channels.(app.dn{index}).TrigButtonGroup = uibuttongroup(app.channelPanels.(app.dn{index}), ...
                'Position', [177, 400-height-54, 25, height], 'Title', 'Trig', 'FontSize', 10, ...
                'AutoResizeChildren', 'off');
            % Create global check or uncheck all box
            app.channels.(app.dn{index}).globalCheck = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 320, 25, 25], ...
                    'Value', 1, 'Text', '', ...
                    'ValueChangedFcn', createCallbackFcn(app, @CheckBoxValueChanged, true), ...
                    'Tooltip', 'Check or uncheck display status for all channels on this DAQ');
            % Create each channel
            for i = 1:numInit
                app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                    'Items', {'Off', 'On'}, 'Value', 'On', ...
                    'Position', [25, 308 - 25*(i-1), 23, 10], ...
                    'ValueChangedFcn', createCallbackFcn(app, @channelEnableCallbackFcn, true), ...
                    'Tag', num2str(i));
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 300 - 25*(i-1), 25, 25], ...
                    'Value', 1, 'Text', '', 'Tag', num2str(i), ...
                    'ValueChangedFcn', createCallbackFcn(app, @chanDisplayValueChangedFcn, true));
                app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                    'text', 'Value', ['ch',num2str(i)], ...
                    'Position', [102, 300 - 25*(i-1), 69, 20]);
                app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(numInit-i), 16, 16], 'Text', '', 'Tag', num2str(i));
            end
            % Create labels
            app.channels.(app.dn{index}).NumberofChannelsEditFieldLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Number of Channels:', 'Position', [9,337,119,22]);
            app.channels.(app.dn{index}).EnableLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Enable', 'Position', [22,318,42,22], 'FontSize', 10);
            app.channels.(app.dn{index}).ChannelNameLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Channel Name', 'Position', [100,318,85,22], 'FontSize', 10);
            % Note how many channels
            app.channels.(app.dn{index}).howMany = numInit;

            % Alter sync controls
            app.sync.(app.dn{index}).MasterButton = uitogglebutton(app.sync.MasterButtonGroup, ...
                'Position', [0, 155 - 25*(index-1), 50, 22], 'Text', app.dn{index}, 'Tag', app.dn{index});
            app.sync.(app.dn{index}).PFInum = uieditfield(app.StartupTab, ...
                'numeric', 'Position', [66, 155 - 25*(index-1), 72, 22], 'Value', 0);
            app.sync.(app.dn{index}).clockPFInum = uieditfield(app.ClockingTab, ...
                'numeric', 'Position', [66, 115 - 25*(index-1), 72, 22], 'Value', 1);
        end


        % Function to manually move trigger select buttons vertically 
        function resizeTrigButtons(app, panel, ncurrent, ntotal)
            for i = 1:ncurrent
                app.channels.(panel).(['toggleButton_',num2str(i)]).Position(2) = app.channels.trigOffset+25*(ntotal-i);
            end
        end


        % Function to start up DAQ and plot processes, lock in app state for recording
        function startRecording(app)
            % Reset axes and DAQs
            daqreset;
            cla(app.mainPlot)
            % Declare Rates 
            app.rate.DAQ = app.DAQRateHzEditField.Value; %(Hz)
            app.rate.plot = app.PlotRateHzEditField.Value; %(Hz)
            app.rate.plotLength = app.RecordTimesEditField.Value; %(s)
            app.rate.buffer = app.rate.plotLength * app.rate.DAQ; %(samples)
            app.rate.read = round(app.rate.DAQ / app.ReadRateHzEditField.Value); %(samples)
            % Change looks of data acquisition switch
            app.ArmDataAcquisitionSwitch.FontWeight = 'bold';
            app.ArmDataAcquisitionSwitch.FontColor = [0.635,0.078,0.184];
            % Make folder in chosen directory for today's date (if not already there)
            app.name.projFolder = app.ProjectFolderEditField.Value;
            app.name.path = strcat(app.name.projFolder, filesep, datestr(now,'yyyy_mm_dd'));
            if ~exist(app.name.path, 'dir')
                mkdir(app.name.projFolder, datestr(now,'yyyy_mm_dd'))
            end
            % Get which DAQ is master for clock and start trigger
            app.sync.masterDAQ = app.sync.MasterButtonGroup.SelectedObject.Tag;
            % Get channel information: What channels to what device, which
            % are active, which display, total # of channels, etc
            getChannelInformation(app)

            % Create DAQ session
            app.d = daq('ni');
            app.d.Rate = app.rate.DAQ;
            app.d.ScansAvailableFcnCount = app.rate.read;

            % Preallocate data
            app.data.time = nan(app.rate.buffer, 1);
            app.data.data = nan(app.rate.buffer, sum(app.channels.active));

            % Initialize filename of this test
            % TODO: Make smarter. Right now editfield can't display current
            % filename without getting confused and in next run adding more shit to it
            filenameField = app.FilenameoptionalEditField.Value;
            % If filename in field, but no 3-digit numbers, add _000.mat
            if ~isempty(filenameField) && isnan(str2double(filenameField(end-2:end)))
                app.name.filename = [filenameField, '_000.h5'];
            % Filename in field, with numbers
            elseif ~isempty(filenameField) && ~isnan(str2double(filenameField(end-2:end)))
                app.name.filename = [filenameField, '.h5'];
            % Empty filename field
            elseif isempty(filenameField)
                app.name.filename = [char(datetime('now', 'format', 'yyyy_MM_dd')), '_000.h5'];
            end
            
            % If existing file matches, increment numbers
            if exist(fullfile(app.name.path, app.name.filename), 'file')
                split = strsplit(app.name.filename, '_');
                number = split{end}(1:3);
                app.name.filename = strjoin({split{1:end-1}, [num2str(str2double(number)+1,'%03.f'), '.h5']}, '_');
            end
            app.FilenameoptionalEditField.Value = app.name.filename(1:end-3);

            % If running camera system, fire that up and wait for it to get ready
            if app.UseCameraCheckBox.Value
                % Note camera runtime record time will always be 2 seconds
                % behind total length of recording to make sure every frame
                % is observed on analog inputs
                system_command = ['conda run -n campy2 campy-acquire ', ...
                    '--videoFolder ', fullfile(app.name.path, app.FilenameoptionalEditField.Value), ' ', ...
                    '--videoFilename ', app.FilenameoptionalEditField.Value, ' ', ...
                    '--frameRate ', num2str(app.CameraRateHzEditField.Value), ' ', ...
                    '--recTimeInSec ', num2str(app.RecordTimesEditField.Value - 2), ' ', ...
                    app.ConfigFilePathEditField.Value, ' &'];
                system(system_command);
                pause(10) % camera system takes a while to start up
            end

            % Loop over active DAQs
            for dq = app.DAQsListBox.Value
                i = dq{1};

                % Convert to NI channel numbers
                useChannels = app.channels.devID == i & app.channels.active;
                actualChanNumbers = app.channels.deviceIndex(useChannels) - 1;
                % Shift channel numbers if differential to reflect actual channel numbering used by NI
                if contains(app.channels.(i).terminalConfig.Value, 'Differential')
                    actualChanNumbers(actualChanNumbers > 7) = actualChanNumbers(actualChanNumbers > 7) + 8;
                end
                % Create channels
                addinput(app.d, i, actualChanNumbers, 'Voltage');

                % Disable per-DAQ fields that shouldn't change during recording
                for j = 1:app.channels.(i).howMany
                    app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'Off';
                end
                for j = 1:length(app.recordingDisableChannelFields)
                    app.channels.(i).(app.recordingDisableChannelFields{j}).Enable = 'Off';
                end
                for j = 1:length(app.recordingDisableSyncFields)
                    app.sync.(i).(app.recordingDisableChannelFields{j}).Enable = 'Off';
                end
            end

            % Set terminalConfig for each channel
            activeinds = find(app.channels.active);
            for i = 1:sum(app.channels.active)
                app.d.Channels(i).TerminalConfig = app.channels.(app.channels.devID(activeinds(i))).terminalConfig.Value;
            end

            % Set triggering callback function
            % Trigger from one of the analog input channels
            if strcmpi(app.TrigModeSwitch.Value, 'ExtTrigTab') 
                % (two grab data functions based on triggering type)
                if strcmpi(app.RiseOrFallSwitch.Value, 'Rising')
                    app.d.ScansAvailableFcn = @app.GrabDataRise;
                else
                    app.d.ScansAvailableFcn = @app.GrabDataFall;
                end
            % Trigger from click button only
            else
                app.d.ScansAvailableFcn = @app.GrabData;
            end

            % Plot setup
            % Remove lines from previous run (if there were any)
            chanFieldNames = fieldnames(app.channels);
            if any(contains(chanFieldNames, 'line_'))
                for lineHandle = chanFieldNames(contains(chanFieldNames, 'line_'))
                    delete(app.channels.(lineHandle{1}))
                end
            end
            % Setup new lines for each channel
            for j = activeinds
                % Create placeholder plot lines for each channel to update
                app.channels.(['line_',num2str(j)]) = line(app.mainPlot, nan, nan, ...
                    'color', app.plt.colors(j,:), ...
                    'DisplayName', app.channels.name(j));
                if ~app.channels.display(j)
                    app.channels.(['line_',num2str(j)]).HandleVisibility = 'off';
                end
            end
            % Create plot legend
            app.plt.legend = legend(app.mainPlot, 'location', 'northwest');

            % Set up triggering, clocking
            for dq = app.DAQsListBox.Value
                i = dq{1};
                % If this DAQ is minion
                if ~strcmp(i, app.sync.masterDAQ)
                    addtrigger(app.d, "Digital", "StartTrigger", ...
                        [app.sync.masterDAQ, '/PFI', num2str(app.sync.(app.sync.masterDAQ).PFInum.Value)], ...
                        [i, '/PFI', num2str(app.sync.(i).PFInum.Value)]);
                    if app.DoClockingCheckBox.Value
                        addclock(app.d, ...
                            "ScanClock", ...
                            [app.sync.masterDAQ, '/PFI', num2str(app.sync.(app.sync.masterDAQ).clockPFInum.Value)], ...
                            [i, '/PFI', num2str(app.sync.(i).clockPFInum.Value)])
                    end
                end
            end

            % Save which channel is trigger
            trigch = str2double(app.channels.(app.sync.masterDAQ).TrigButtonGroup.SelectedObject.Tag);
            % Get global index from daq-local trigger channel index
            app.sync.trigind = app.channels.activeIndex(app.channels.devID == app.sync.masterDAQ & app.channels.deviceIndex == trigch);

            % Disable fields that shouldn't change during recording
            for i = 1:length(app.recordingDisableFields)
                app.(app.recordingDisableFields{i}).Enable = 'Off';
            end
            app.sync.MasterButtonGroup.Enable = 'Off';

            % Create plot timer
            app.rate.plot = app.PlotRateHzEditField.Value;
            app.plt.plotTimer = timer('TimerFcn', {@app.plotTimerUpdateFcn},...
                'ExecutionMode', 'fixedRate', 'busymode', 'drop',...
                'StartDelay', 0.2, 'Period', 1/app.rate.plot, 'Name', 'timer-1');
            % Time x-axis stand-in to make plotting more visually appealing
            app.data.fakeTime = linspace(0, app.rate.plotLength, app.rate.buffer);
            
            % Start master DAQ
            start(app.d, "Continuous");
            start(app.plt.plotTimer);
        end


        function saveData(app)
            % Note stop will sometimes get called many sequential times.
            % But that shouldn't cause bugs so whatever
            stop(app.d) 
            % Write data to hdf5 file with moderate compression
            filename = fullfile(app.name.path, app.name.filename);
            channelNames = ["time", app.channels.name(app.channels.active)];
            h5create(filename, "/data", size(app.data.data) + [0,1], ...
                "Deflate", 4, "ChunkSize", [1000, size(app.data.data,2)+1]);
            h5create(filename, "/names", size(channelNames), "DataType", "string");
            h5write(filename, "/data", [app.data.time, app.data.data]);
            h5write(filename, "/names", channelNames);
        end

        % Function to restart DAQ timers+listeners, and reset app state for next recording
        function stopRecordingReset(app)
            stop(app.d) % This is called twice sometimes but I guess you can never be too safe lol

            for dq = app.DAQsListBox.Value
                i = dq{1};
                % Re-enable fields 
                for j = 1:app.channels.(i).howMany
                    app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'On';
                end
                for j = 1:length(app.recordingDisableChannelFields)
                    app.channels.(i).(app.recordingDisableChannelFields{j}).Enable = 'On';
                end
                for j = 1:length(app.recordingDisableSyncFields)
                    app.sync.(i).(app.recordingDisableChannelFields{j}).Enable = 'On';
                end

            end
            % Stop plot timer
            if strcmp(get(app.plt.plotTimer,'Running'),'on')
                stop(app.plt.plotTimer)
            end
            % Reset GUI, re-enable everything
            app.ArmDataAcquisitionSwitch.Value = '';
            app.ArmDataAcquisitionSwitch.FontWeight = 'normal';
            app.ArmDataAcquisitionSwitch.FontColor = [0,0,0];
            app.TriggerButton.Enable = 'Off';
            app.TriggerButton.Value = false;
            % Re-enable fields
            for i = 1:length(app.recordingDisableFields)
                app.(app.recordingDisableFields{i}).Enable = 'On';
            end
            app.sync.MasterButtonGroup.Enable = 'On';
        end

        function loadState(app, loadfile)
            if ~exist(loadfile, 'file')
                return
            elseif ~ismember('state', who('-file', loadfile))
                warning('Not a valid state file!')
                return
            end
            state = load(loadfile, 'state');
            state = state.state;
            % Load value of main fields
            for i = 1:length(app.fieldsToSave)
                app.(app.fieldsToSave{i}).Value = state.(app.fieldsToSave{i}).Value;
            end
            % Load acquisition enable state
            app.ArmDataAcquisitionSwitch.Enable = state.ArmDataAcquisitionSwitch.Enable;
            app.FolderNote.Visible = state.FolderNote.Visible;
            % Load values from channels fields, regenerate channel panels
            app.dn = state.dn;
            for i = state.channels.indices
                dq = app.dn{i};
                app.channels.(dq).howMany = state.channels.(dq).howMany;
                createChannelPanel(app, i, app.channels.(dq).howMany)
                % Update states for this panel
                for j = 1:app.channels.(dq).howMany
                    for k = app.perChannelFieldsToSave
                        app.channels.(dq).([k{1},num2str(j)]).Value = state.channels.(dq).([k{1},num2str(j)]).Value;
                    end
                end
                app.channels.(dq).DAQNameField.Value = state.channels.(dq).DAQNameField.Value;
                app.channels.(dq).terminalConfig.Value = state.channels.(dq).terminalConfig.Value;
                app.channels.(dq).TrigButtonGroup.Visible = state.channels.(dq).TrigButtonGroup.Visible;
                % Load sync field states
                app.sync.(dq).MasterButton.Value = state.sync.(dq).MasterButton.Value;
                app.sync.(dq).PFInum.Value = state.sync.(dq).PFInum.Value;
                app.sync.(dq).clockPFInum.Value = state.sync.(dq).clockPFInum.Value;
            end
            % Update channel information
            app.getChannelInformation();
        end
        function saveState(app, savefile)
            state = struct();
            % Save value of important fields
            for i = 1:length(app.fieldsToSave)
                state.(app.fieldsToSave{i}).Value = app.(app.fieldsToSave{i}).Value;
            end
            % Save whether acquisition was enabled or not
            state.ArmDataAcquisitionSwitch.Enable = app.ArmDataAcquisitionSwitch.Enable;
            state.FolderNote.Visible = app.FolderNote.Visible;
            % Save key values from channels to allow regenerating channel panels
            state.dn = app.dn;
            state.channels.indices = find(~cellfun(@isempty, app.dn));
            state.channels.indexAvailable = app.channels.indexAvailable;
            % Loop over active DAQs with channelPanels
            for i = state.channels.indices
                dq = app.dn{i};
                state.channels.(dq).howMany = app.channels.(dq).howMany;
                % Loop over channels in this panel, save states
                for j = 1:state.channels.(dq).howMany
                    for k = app.perChannelFieldsToSave
                        state.channels.(dq).([k{1},num2str(j)]).Value = app.channels.(dq).([k{1},num2str(j)]).Value;
                    end
                end
                state.channels.(dq).DAQNameField.Value = app.channels.(dq).DAQNameField.Value;
                state.channels.(dq).terminalConfig.Value = app.channels.(dq).terminalConfig.Value;
                state.channels.(dq).TrigButtonGroup.Visible = app.channels.(dq).TrigButtonGroup.Visible;
                % Save sync field states
                state.sync.(dq).MasterButton.Value = app.sync.(dq).MasterButton.Value;
                state.sync.(dq).PFInum.Value = app.sync.(dq).PFInum.Value;
                state.sync.(dq).clockPFInum.Value = app.sync.(dq).clockPFInum.Value;
            end
            % Save to file
            save(savefile,'state');
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set up pre-defined constants held as sub-fields
            % Positioning constants
            app.channels.trigOffset = 8; %px
            % Plot colors (assuming nobody will want to acquire and plot more than 100 channels)
            app.plt.colors = lines(100);

            % Populate DAQs list box based on active and connected DAQs
            % If they have data acquisition toolbox installed
            if exist('daqlist', 'file') 
                daqreset % reset needed to update daqlist
                daqs = daqlist;
                if isempty(daqs)
                    warning(['No DAQs are turned on and connected\nWill run in demo mode, many features will not work'], 1)
                    app.DAQsListBox.Items = {'fakeDev1', 'fakeDev2', 'fakeDev3', 'fakeDev4'};
                else
                    app.DAQsListBox.Items = cellstr(daqs{:,2})';
                end
            % Otherwise spit warning, give fake DAQ names for demos/testing
            else
                warning(['Data acquisition toolbox likely not installed!\n' ...
                    'Will run in demo mode, many features will not work'], 1)
                app.DAQsListBox.Items = {'fakeDev1', 'fakeDev2', 'fakeDev3', 'fakeDev4'};
            end
            % Create sync controls
            app.sync.MasterButtonGroup =  uibuttongroup(app.StartupTab, ...
                'Position', [3, 0, 50, 200], 'Title', 'Master', 'AutoResizeChildren', 'off');
            % For the first connected+active DAQ, run channel panel creation
            app.DAQsListBox.Value = app.DAQsListBox.Items(1);
            app.dn = app.DAQsListBox.Items(1);
            createChannelPanel(app, 1, 10);
            app.channels.indexAvailable = [2];
        end

        % Button pushed function: FolderButton
        function FolderSelect(app, event)
            % Let the user select the folder to store data
            folderName = uigetdir(pwd, 'Select directory for saving data');
            app.ProjectFolderEditField.Value = folderName;
            % Enable DAQ arming, remove note
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.FolderNote.Visible = 'Off';
        end

        % Value changed function: TriggerButton
        function StartLoggingSession(app, event)
            value = app.TriggerButton.Value;
            if value
                % Disable file selectors, BeginLoggingButton on GUI
                app.TriggerButton.Enable = 'Off';
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameoptionalEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                % Reset GUI, save data (if any), and stop data recording
                saveData(app)
                stopRecordingReset(app)
            end
        end

        % Value changed function: ArmDataAcquisitionSwitch
        function ArmDataAcquisitionSwitchValueChanged(app, event)
            value = app.ArmDataAcquisitionSwitch.Value;
            drawnow()
            %Actions to do when data acquisition has been armed
            if strcmp(value,'Armed')
                startRecording(app)
            % Actions to do when switch is disarmed
            else
                stopRecordingReset(app)
            end
            % Enable final trigger if data acq. has been armed and folder specified
            if ~isempty(app.ProjectFolderEditField.Value) && strcmp(value,'Armed')
                app.TriggerButton.Enable = 'On';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Stop sessions, catch errors in case they didn't exist or were already killed
            try
                stop(app.d)
                % Stop timers
                if strcmp(get(app.plt.plotTimer,'Running'),'on')
                    stop(app.plt.plotTimer)
                end
            catch
            end
            
            delete(app)
        end

        % Value changed function: TrigModeSwitch
        function TrigModeChangedFcn(app, event)
            value = app.TrigModeSwitch.Value;
            app.TabGroup.SelectedTab = app.(value);
            if strcmp(value, 'ExtTrigTab')
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'on';
                end
            else
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'off';
                end
            end
        end

        % Callback function
        function ChangeNumChannelsFcn(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % If new # of channels less than current, remove some channels
            if value < app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-54, 25, height];
                % TODO: Make actual references to channel panel dimensions
                % (rather than hard-coded 400 constant)
                % Loop over channels to remove
                for i = (value+1):app.channels.(app.dn{index}).howMany
                    delete(app.channels.(app.dn{index}).(['chanOn_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanName_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]))
                end
                resizeTrigButtons(app, app.dn{index}, value, value)
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
                
            % If new number more than current, add some channels
            elseif value > app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-54, 25, height];
                resizeTrigButtons(app, app.dn{index}, app.channels.(app.dn{index}).howMany, value)
                % Loop over channels to create
                for i = (app.channels.(app.dn{index}).howMany+1):value
                    % TODO: Have one function to call in both situations for creating channels to reduce code duplication
                    app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                        'Items', {'Off', 'On'}, 'Value', 'On', ...
                        'Position', [25, 308 - 25*(i-1), 23, 10], ...
                        'ValueChangedFcn', createCallbackFcn(app, @channelEnableCallbackFcn, true), ...
                        'Tag', num2str(i));
                    app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                        'Position', [78, 300 - 25*(i-1), 25, 25], ...
                        'Value', 1, 'Text', '', 'Tag', num2str(i), ...
                        'ValueChangedFcn', createCallbackFcn(app, @chanDisplayValueChangedFcn, true));
                    app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                        'text', 'Value', ['ch',num2str(i)], ...
                        'Position', [102, 300 - 25*(i-1), 69, 20]);
                    app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(value-i), 16, 16], 'Text', '', 'Tag', num2str(i));
                end
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
            end
            % If same number of channels, do nothing
        end

        % Value changed function: DAQsListBox
        function NewDAQFcn(app, event)
            % Get removed DAQs, call remove channel function on them
            removedDAQs = event.PreviousValue(~contains(event.PreviousValue, event.Value));
            for i = 1:length(removedDAQs)
                % Delete
                delete(app.channelPanels.(removedDAQs{i}))
                delete(app.sync.(removedDAQs{i}).MasterButton)
                delete(app.sync.(removedDAQs{i}).PFInum)
                delete(app.sync.(removedDAQs{i}).clockPFInum)
                % clear out to allow new boxes to take this location
                index = find(strcmp(app.dn, removedDAQs(i)));
                app.dn{index} = '';
                app.channels.indexAvailable(end+1) = index;
            end
            
            % Get new DAQs, call create channel function on them
            newDAQs = event.Value(~contains(event.Value, event.PreviousValue));
            for i = 1:length(newDAQs)
                % If adding this channel panel to end
                if length(app.channels.indexAvailable) == 1
                    index = app.channels.indexAvailable(1);
                    app.channels.indexAvailable = app.channels.indexAvailable + 1;
                    app.dn{end+1} = newDAQs{i};
                % If putting in open middle slot
                else
                    sortAvailable = sort(app.channels.indexAvailable);
                    index = sortAvailable(1);
                    app.channels.indexAvailable(app.channels.indexAvailable==index) = [];
                    app.dn{index} = newDAQs{i};
                end
                % Create panel
                createChannelPanel(app, index, 10);
            end

        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % Loop over number of channels, check or uncheck all
            for i = 1:app.channels.(app.dn{index}).howMany
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]).Value = value;
            end

        end

        % Menu selected function: OpenMenu
        function OpenMenuFcn(app, event)
            [file, path] = uigetfile('*.mat', 'Select valid file to load app state from');
            loadState(app, fullfile(path, file))
        end

        % Menu selected function: SaveMenu
        function SaveMenuFcn(app, event)
            [file, path] = uiputfile('*.mat', 'Select location and name to save app state file under');
            saveState(app, fullfile(path, file))
        end

        % Callback function
        function DAQNameValueChangedFcn(app, event)
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % Change channel panels title
            app.channelPanels.(app.dn{index}).Title = [event.Value, ' channels'];
            % Change master button text
            app.sync.(app.dn{index}).MasterButton.Text = event.Value;
        end

        % Value changed function: DoClockingCheckBox
        function DoClockingValueChangedFcn(app, event)
            value = app.DoClockingCheckBox.Value;
            if value
                enableState = 'On';
            else
                enableState = 'Off';
            end
            for dq = app.DAQsListBox.Value
                i = dq{1};
                app.sync.(i).PFInum.Enable = enableState;
                app.sync.(i).clockPFInum.Enable = enableState;
            end
        end

        % Callback function
        function channelEnableCallbackFcn(app, event)
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % get which channel index this is
            chind = str2double(event.Source.Tag);
            % If switched to off
            if strcmpi(event.Source.Value, 'Off')
                % Set display checkbox to off, disable
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(chind)]).Value = 0;
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(chind)]).Enable = 'off';
            else
                % Set display checkbox to on, enable
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(chind)]).Value = 1;
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(chind)]).Enable = 'on';
            end
        end

        % Callback function
        function chanDisplayValueChangedFcn(app, event)
            % Check Tag to know which channel panel and channel this is
            index = str2double(event.Source.Parent.Tag);
            chind = str2double(event.Source.Tag);
            globalind = find(app.channels.devID == app.dn{index} & app.channels.deviceIndex == chind);
            % Update display tag no matter what
            if event.Value
                app.channels.display(globalind) = true;
            else
                app.channels.display(globalind) = false;
            end
            % Change plot if channel is enabled
            if app.channels.active(globalind) && isfield(app.channels, ['line_',num2str(globalind)])
                % Switched to display
                if event.Value
                    offset = app.SepbyEditField.Value;
                    set(app.channels.(['line_',num2str(globalind)]), ...
                        'XData', app.data.fakeTime(1:length(app.data.data)), ...
                        'YData', app.data.data(:,app.channels.activeIndex(globalind)) + (globalind-1)*offset);
                    app.channels.(['line_',num2str(globalind)]).HandleVisibility = 'on';
                % Switched to not display
                else
                    set(app.channels.(['line_',num2str(globalind)]), 'XData', nan, 'YData', nan);
                    app.channels.(['line_',num2str(globalind)]).HandleVisibility = 'off';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenMenu = uimenu(app.UIFigure);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuFcn, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuFcn, true);
            app.SaveMenu.Text = 'Save';
            app.mainPlot = uiaxes(app.UIFigure);
            xlabel(app.mainPlot, 'Time (s)')
            ylabel(app.mainPlot, 'Voltage')
            app.FileSavingParametersPanel = uipanel(app.UIFigure);
            app.FileSavingParametersPanel.Title = 'File Saving Parameters';
            app.FilenameoptionalEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.FilenameoptionalEditFieldLabel.Text = 'Filename (optional)';
            app.FilenameoptionalEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.ProjectFolderEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.FolderButton = uibutton(app.FileSavingParametersPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelect, true);
            app.FolderButton.Text = '...';
            app.FolderNote = uilabel(app.FileSavingParametersPanel);
            app.FolderNote.Text = 'Note: Project Folder must be selected to start acquiring data!';
            app.RateTimeControlsPanel = uipanel(app.UIFigure);
            app.RateTimeControlsPanel.Title = 'Rate/Time Controls';
            app.DAQRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.DAQRateHzEditFieldLabel.Text = 'DAQ Rate (Hz)';
            app.DAQRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.DAQRateHzEditField.Value = 10000;
            app.PlotRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.PlotRateHzEditFieldLabel.Text = 'Plot Rate (Hz)';
            app.PlotRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.PlotRateHzEditField.Value = 2;
            app.RecordTimesEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.RecordTimesEditFieldLabel.Text = 'Record Time (s)';
            app.RecordTimesEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.RecordTimesEditField.Value = 20;
            app.ReadRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.ReadRateHzEditFieldLabel.Text = 'Read Rate (Hz)';
            app.ReadRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.ReadRateHzEditField.Value = 5;
            app.TriggerControlsPanel = uipanel(app.UIFigure);
            app.TriggerControlsPanel.Title = 'Trigger Controls';
            app.TabGroup = uitabgroup(app.TriggerControlsPanel);
            app.ExtTrigTab = uitab(app.TabGroup);
            app.ExtTrigTab.Title = 'Ext Trig';
            app.SamplesposttriggerEditFieldLabel = uilabel(app.ExtTrigTab);
            app.SamplesposttriggerEditFieldLabel.Text = 'Samples post-trigger';
            app.SamplesposttriggerEditField = uieditfield(app.ExtTrigTab, 'numeric');
            app.SamplesposttriggerEditField.Value = 15;
            app.VoltagethresholdEditFieldLabel = uilabel(app.ExtTrigTab);
            app.VoltagethresholdEditFieldLabel.Text = 'Voltage threshold';
            app.VoltagethresholdEditField = uieditfield(app.ExtTrigTab, 'numeric');
            app.VoltagethresholdEditField.Value = 2;
            app.RiseOrFallSwitch = uiswitch(app.ExtTrigTab, 'slider');
            app.RiseOrFallSwitch.Items = {'Rising', 'Falling'};
            app.RiseOrFallSwitch.Value = 'Rising';
            app.ClickTrigTab = uitab(app.TabGroup);
            app.ClickTrigTab.Title = 'Click Trig';
            app.TriggerButton = uibutton(app.ClickTrigTab, 'state');
            app.TriggerButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingSession, true);
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.TrigModeSwitch = uiswitch(app.TriggerControlsPanel, 'slider');
            app.TrigModeSwitch.Items = {'External', 'Click Button'};
            app.TrigModeSwitch.ItemsData = {'ExtTrigTab', 'ClickTrigTab'};
            app.TrigModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TrigModeChangedFcn, true);
            app.TrigModeSwitch.Value = 'ExtTrigTab';
            app.ArmDataAcquisitionPanel = uipanel(app.UIFigure);
            app.ArmDataAcquisitionPanel.Title = 'Arm Data Acquisition';
            app.ArmDataAcquisitionSwitch = uiswitch(app.ArmDataAcquisitionPanel, 'slider');
            app.ArmDataAcquisitionSwitch.Items = {'', 'Armed'};
            app.ArmDataAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmDataAcquisitionSwitchValueChanged, true);
            app.ArmDataAcquisitionSwitch.Enable = 'off';
            app.ArmDataAcquisitionSwitch.Value = '';
            app.DAQsLabel = uilabel(app.UIFigure);
            app.DAQsLabel.Text = 'DAQs:';
            app.DAQsListBox = uilistbox(app.UIFigure);
            app.DAQsListBox.Items = {};
            app.DAQsListBox.Multiselect = 'on';
            app.DAQsListBox.ValueChangedFcn = createCallbackFcn(app, @NewDAQFcn, true);
            app.DAQsListBox.Value = {};
            app.SynchronizationPanel = uipanel(app.UIFigure);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.TabGroup2 = uitabgroup(app.SynchronizationPanel);
            app.StartupTab = uitab(app.TabGroup2);
            app.StartupTab.Title = 'Startup';
            app.PFIchannelLabel = uilabel(app.StartupTab);
            app.PFIchannelLabel.Text = 'PFI channel #';
            app.ClockingTab = uitab(app.TabGroup2);
            app.ClockingTab.Title = 'Clocking';
            app.DoClockingCheckBox = uicheckbox(app.ClockingTab);
            app.DoClockingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoClockingValueChangedFcn, true);
            app.DoClockingCheckBox.Text = '';
            app.DoClockingCheckBox.Value = true;
            app.SyncDAQsviaclockfromamasterDAQLabel = uilabel(app.ClockingTab);
            app.SyncDAQsviaclockfromamasterDAQLabel.Text = {'Sync DAQs via clock '; 'from a master DAQ'};
            app.PFIchannelLabel_2 = uilabel(app.ClockingTab);
            app.PFIchannelLabel_2.Text = 'PFI channel #';
            app.CameraTab = uitab(app.TabGroup2);
            app.CameraTab.Title = 'Camera';
            app.UseCameraCheckBox = uicheckbox(app.CameraTab);
            app.UseCameraCheckBox.Text = 'Use Camera';
            app.ConfigFilePathEditFieldLabel = uilabel(app.CameraTab);
            app.ConfigFilePathEditFieldLabel.Text = 'Config File Path';
            app.ConfigFilePathEditField = uieditfield(app.CameraTab, 'text');
            app.CameraRateHzEditFieldLabel = uilabel(app.CameraTab);
            app.CameraRateHzEditFieldLabel.Text = 'Camera Rate (Hz)';
            app.CameraRateHzEditField = uieditfield(app.CameraTab, 'numeric');
            app.CameraRateHzEditField.Value = 300;
            app.SepbyLabel = uilabel(app.UIFigure);
            app.SepbyLabel.Text = 'Sep. by:';
            app.SepbyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end