classdef DataLogger_triggered < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChannelsPanel                  matlab.ui.container.Panel
        chanOn_8                       matlab.ui.control.ToggleSwitch
        chanOn_7                       matlab.ui.control.ToggleSwitch
        chanOn_6                       matlab.ui.control.ToggleSwitch
        chanOn_5                       matlab.ui.control.ToggleSwitch
        chanOn_4                       matlab.ui.control.ToggleSwitch
        chanOn_3                       matlab.ui.control.ToggleSwitch
        chanOn_2                       matlab.ui.control.ToggleSwitch
        chanOn_1                       matlab.ui.control.ToggleSwitch
        chanName_1                     matlab.ui.control.EditField
        EnableLabel                    matlab.ui.control.Label
        ChannelNameLabel               matlab.ui.control.Label
        chanName_8                     matlab.ui.control.EditField
        chanName_7                     matlab.ui.control.EditField
        chanName_6                     matlab.ui.control.EditField
        chanName_5                     matlab.ui.control.EditField
        chanName_4                     matlab.ui.control.EditField
        chanName_3                     matlab.ui.control.EditField
        chanName_2                     matlab.ui.control.EditField
        RateTimeControlsPanel          matlab.ui.container.Panel
        RecordTimesEditField           matlab.ui.control.NumericEditField
        RecordTimesEditFieldLabel      matlab.ui.control.Label
        PlotRateHzEditField            matlab.ui.control.NumericEditField
        PlotRateHzEditFieldLabel       matlab.ui.control.Label
        DAQRateHzEditField             matlab.ui.control.NumericEditField
        DAQRateHzEditFieldLabel        matlab.ui.control.Label
        DAQLoggingControlsPanel        matlab.ui.container.Panel
        ArmDataAcquisitionSwitch       matlab.ui.control.Switch
        ArmDataAcquisitionSwitchLabel  matlab.ui.control.Label
        TriggerButton                  matlab.ui.control.StateButton
        FileSavingParametersPanel      matlab.ui.container.Panel
        FolderNote                     matlab.ui.control.Label
        FolderButton                   matlab.ui.control.Button
        ProjectFolderEditField         matlab.ui.control.EditField
        ProjectFolderEditFieldLabel    matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        mainPlot                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        s1      % DAQ session 
        data    % Input data matrix
        name    % Filenames and folders
        FID     % fopen fileID
        activeChannels % which channels are activated, alters saveForm
        saveForm% fprintf save format, must update if new inputs added
        rate    % All relevant information for timing/rates
        indx    % Relevant indices to store
        plotTimer
        saveTimer
        lh1_in  % Listener for input
    end

    methods (Access = private)
    
        function GrabData1(app,event,info)
            %Append matrices with new data
            app.data.time = [app.data.time;info.TimeStamps];
            app.data.data = [app.data.data;info.Data(:,1)];
            % trim to rolling buffer size
            if length(app.data.time) >= app.rate.buffer
                app.data.time = app.data.time(end-app.rate.buffer+1:end);
                app.data.data = app.data.data(end-app.rate.buffer+1:end,:);
            end
        end
        
    
        function plotTimerUpdateFcn(app,src,event)
            %Update Plots
            if ~isempty(app.data.time) && ~isempty(app.data.sgtime0)
                plot(app.mainPlot, app.data.time, app.data.data)
            end
        end
    
%         function saveTimerUpdateFcn(app,src,event)
%             %Save Data into file
%             if app.indx.save < length(app.data.time)
%                 savebatch = [app.data.time(app.indx.save:end),app.data.temp(app.indx.save:end,:),app.data.pos(app.indx.save:end,:),...
%                     app.data.posVolts(app.indx.save:end,:),app.data.sgtime(app.indx.save:end),app.data.sg(app.indx.save:end,:)];
%                 for i = 1:length(app.data.time(app.indx.save:end))
%                     fprintf(app.FID,app.saveForm,savebatch(i,:));
%                 end
%                 app.indx.save = length(app.data.time)+1;
%             end
%         end

        function getChannelActiveState(app)
            values = [app.chanOn_1.Value, app.chanOn_2.Value,...
                app.chanOn_3.Value, app.chanOn_4.Value,...
                app.chanOn_5.Value, app.chanOn_6.Value,...
                app.chanOn_7.Value, app.chanOn_8.Value];
            app.activeChannels = values=="On";
        end


        function stopRecordingReset(app)
%             %Delete listeners
%             delete(app.lh1_in)
%             %Stop sessions
%             stop(app.s1)
            %Stop Timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            if strcmp(get(app.saveTimer,'Running'),'on')
                stop(app.saveTimer)
            end
%             %Add final data to file, write footer and close
%             if app.indx.save > length(app.data.time)
%                 savebatch = [app.data.time(app.indx.save:end),app.data.temp(app.indx.save:end,:),app.data.pos(app.indx.save:end,:),...
%                     app.data.posVolts(app.indx.save:end,:),app.data.sgtime(app.indx.save:end),app.data.sg(app.indx.save:end,:)];
%                 for i = 1:length(app.data.time(app.indx.save:end))
%                     fprintf(app.FID,app.saveForm,savebatch(i,:));
%                 end
%                 app.indx.save = length(app.data.time)+1;
%             end
            %Close out file
            fclose('all');
            %Reset GUI and System
            app.ArmDataAcquisitionSwitch.Value = '';
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.ArmDataAcquisitionSwitch.FontWeight = 'normal';
            app.ArmDataAcquisitionSwitch.FontColor = [0,0,0];
            app.TriggerButton.Enable = 'Off';
            app.TriggerButton.Value = false;
            app.ProjectFolderEditField.Enable = 'On';
            app.FilenameEditField.Enable = 'On';
            app.FolderButton.Enable = 'On';
            app.DAQRateHzEditField.Enable = 'On';
            app.PlotRateHzEditField.Enable = 'On';
            app.RecordTimesEditField.Enable = 'On';
            app.chanOn_1.Enable = 'On';
            app.chanOn_2.Enable = 'On';
            app.chanOn_3.Enable = 'On';
            app.chanOn_4.Enable = 'On';
            app.chanOn_5.Enable = 'On';
            app.chanOn_6.Enable = 'On';
            app.chanOn_7.Enable = 'On';
            app.chanOn_8.Enable = 'On';
            %Clear out, re-delcare matrices and indices
            app.data.time = [];
            app.data.data = [];
            app.indx.save = 1;
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % For now nothing...
            
        end

        % Button pushed function: FolderButton
        function FolderSelect(app, event)
            % Let the user select the folder to store data
            dialogTitle = 'Select directory for saving data';
            folderName = uigetdir(pwd,dialogTitle);
            app.ProjectFolderEditField.Value = folderName;
            % Enable DAQ arming, remove note
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.FolderNote.Visible = 'Off';
        end

        % Value changed function: TriggerButton
        function StartLoggingSession(app, event)
            value = app.TriggerButton.Value;
            if value
                %Disable file selectors, BeginLoggingButton on GUI
                app.TriggerButton.Enable = 'Off';
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                % Reset GUI, save data (if any), and stop data recording
                stopRecordingReset(app)
            end
        end

        % Value changed function: ArmDataAcquisitionSwitch
        function ArmDataAcquisitionSwitchValueChanged(app, event)
            value = app.ArmDataAcquisitionSwitch.Value;
            
            %Actions to do when data acquisition has been armed
            if strcmp(value,'Armed')
                % Start up and preallocate DAQ 
%                 daq.reset;
                %Declare Rates 
                app.rate.DAQ = app.DAQRateHzEditField.Value; %(Hz)
                app.rate.plot = app.PlotRateHzEditField.Value; %(Hz)
                app.rate.plotLength = app.RecordTimesEditField.Value; %(s)
                app.rate.buffer = app.rate.plotLength * app.rate.plot; %(samples)
                app.rate.save = 0.5; %(Hz) %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% something up here
                %Predeclare data
                app.data.time = [];
                app.data.data = [];
                %Predeclare indices
                app.indx.save = 1;
                % Set up save format
                getChannelActiveState(app)
                app.saveForm = strcat('\n', '%f', repmat('\t%f',1,sum(app.activeChannels=='On')-1));
                
                % Change looks of data acquisition switch
                app.ArmDataAcquisitionSwitch.FontWeight = 'bold';
                app.ArmDataAcquisitionSwitch.FontColor = [0.635,0.078,0.184];
                
                %Make folder in chosen directory for today's date (if not already there)
                app.name.projFolder = app.ProjectFolderEditField.Value;
                app.name.path = strcat(app.name.projFolder, filesep, datestr(now,'yyyy_mm_dd'));
                if ~exist(app.name.path)
                    mkdir(app.name.projFolder,datestr(now,'yyyy_mm_dd'))
                end

                %Initialize filename of this test
                if ~isempty(app.FilenameEditField.Value)
                    app.name.filename = strcat(app.FilenameEditField.Value,'.txt');
                else
                    app.name.filename = strcat('testWithNoName',datestr(now,'mm_dd_yy_HH_MM_SS'),'.txt');
                end
                % Increment filename with numbers if same file already exists
                if exist(strcat(app.name.path, filesep, app.name.filename))
                    % Add number if no number
                    if app.name.filename(end-5)~='_'
                        app.name.filename = strcat(app.name.filename(1:end-4), '_1', app.name.filename(end-3:end));
                        app.FilenameEditField.Value = app.name.filename(1:end-4);
                    % Otherwise grab and increment number
                    else
                        number = str2num(app.name.filename(end-4));
                        app.name.filename = strcat(app.name.filename(1:end-5), num2str(number+1), app.name.filename(end-3:end));
                        app.FilenameEditField.Value = app.name.filename(1:end-4);
                    end
                end
                
                % Get column names of active channels, add delimiter between
                app.name.chanNames = {app.chanName_1.Value, app.chanName_2.Value,...
                    app.chanName_3.Value, app.chanName_4.Value, ...
                    app.chanName_5.Value, app.chanName_6.Value, ...
                    app.chanName_7.Value, app.chanName_8.Value};
                app.name.chanNames = strjoin(app.name.chanNames(app.activeChannels=='On'), '\t');
                %Write header for file
                app.FID = fopen(strcat(app.name.path, filesep, app.name.filename),'at+');
                fprintf(app.FID,'\n\n%s\n','DAQ Logger App (v2022)');
                fprintf(app.FID,'%s\n',['Date: ',datestr(now,'mm/dd/yyyy')]);
                fprintf(app.FID,'%s\n',['Start Time: ',datestr(now,'HH:MM:SS')]);
                fprintf(app.FID, '%s\n', app.name.chanNames);
                % TODO: make delimiter a parameter to change to csv easier
                

%                 %CREATE DAQ SESSION 1 (Temp and Voltage ins/outs)
%                 app.s1 = daq.createSession('ni');
% %                 app.rate.DAQ = app.DAQRateHzEditField.Value;
%                 app.s1.Rate = app.rate.DAQ;
%                 %Thermocouples
%                 app.s1.addAnalogInputChannel('cDAQ2Mod1',0,'Thermocouple');
%                 app.s1.Channels(1).ThermocoupleType = 'K';
%                 
%                 app.s1.IsContinuous = true;
%                 %Create input/output listeners
%                 app.lh1_in = app.s1.addlistener('DataAvailable',@app.GrabData1);                
%                 
%                 %Create Plotting Timer
%                 app.rate.plot = app.PlotRateHzEditField.Value;
%                 app.plotTimer = timer('TimerFcn',{@app.plotTimerUpdateFcn},...
%                     'ExecutionMode','fixedRate','busymode','queue',...
%                     'Period',1/app.rate.plot,'Name','timer-1');
%                 start(app.plotTimer);
                
                % Disable fields that shouldn't change during recording
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                app.DAQRateHzEditField.Enable = 'Off';
                app.PlotRateHzEditField.Enable = 'Off';
                app.RecordTimesEditField.Enable = 'Off';
                app.chanOn_1.Enable = 'Off';
                app.chanOn_2.Enable = 'Off';
                app.chanOn_3.Enable = 'Off';
                app.chanOn_4.Enable = 'Off';
                app.chanOn_5.Enable = 'Off';
                app.chanOn_6.Enable = 'Off';
                app.chanOn_7.Enable = 'Off';
                app.chanOn_8.Enable = 'Off';
                
                % Start main session
%                 startBackground(app.s1)
           
            % Actions to do when switch is disarmed
            else
                stopRecordingReset(app)
            end


            %Enable data logging if data acq. has been armed and folder specified
            if ~isempty(app.ProjectFolderEditField.Value) && strcmp(value,'Armed')
                app.TriggerButton.Enable = 'On';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            %Stop sessions, catch errors in case they didn't exist or were already killed
            try
%                 stop(app.s1)
            catch
            end
            %Delete Listeners
%             delete(app.lh1_in)
            %Stop timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            if strcmp(get(app.saveTimer,'Running'),'on')
                stop(app.saveTimer)
            end
            %Close files
            fclose('all');
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.mainPlot = uiaxes(app.UIFigure);
            xlabel(app.mainPlot, 'Time (s)')
            ylabel(app.mainPlot, 'Voltage')
            app.FileSavingParametersPanel = uipanel(app.UIFigure);
            app.FileSavingParametersPanel.Title = 'File Saving Parameters';
            app.FilenameEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.ProjectFolderEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.FolderButton = uibutton(app.FileSavingParametersPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelect, true);
            app.FolderButton.Text = '...';
            app.FolderNote = uilabel(app.FileSavingParametersPanel);
            app.FolderNote.Text = 'Note: Project Folder must be selected to start acquiring data!';
            app.DAQLoggingControlsPanel = uipanel(app.UIFigure);
            app.DAQLoggingControlsPanel.Title = 'DAQ/Logging Controls';
            app.TriggerButton = uibutton(app.DAQLoggingControlsPanel, 'state');
            app.TriggerButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingSession, true);
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.ArmDataAcquisitionSwitchLabel = uilabel(app.DAQLoggingControlsPanel);
            app.ArmDataAcquisitionSwitchLabel.Text = 'Arm Data Acquisition';
            app.ArmDataAcquisitionSwitch = uiswitch(app.DAQLoggingControlsPanel, 'slider');
            app.ArmDataAcquisitionSwitch.Items = {'', 'Armed'};
            app.ArmDataAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmDataAcquisitionSwitchValueChanged, true);
            app.ArmDataAcquisitionSwitch.Enable = 'off';
            app.ArmDataAcquisitionSwitch.Value = '';
            app.RateTimeControlsPanel = uipanel(app.UIFigure);
            app.RateTimeControlsPanel.Title = 'Rate/Time Controls';
            app.DAQRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.DAQRateHzEditFieldLabel.Text = 'DAQ Rate (Hz)';
            app.DAQRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.DAQRateHzEditField.Value = 1000;
            app.PlotRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.PlotRateHzEditFieldLabel.Text = 'Plot Rate (Hz)';
            app.PlotRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.PlotRateHzEditField.Value = 1;
            app.RecordTimesEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.RecordTimesEditFieldLabel.Text = 'Record Time (s)';
            app.RecordTimesEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.RecordTimesEditField.Value = 20;
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.chanName_2 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_2.Value = 'ch2';
            app.chanName_3 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_3.Value = 'ch3';
            app.chanName_4 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_4.Value = 'ch4';
            app.chanName_5 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_5.Value = 'ch5';
            app.chanName_6 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_6.Value = 'ch6';
            app.chanName_7 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_7.Value = 'ch7';
            app.chanName_8 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_8.Value = 'ch8';
            app.ChannelNameLabel = uilabel(app.ChannelsPanel);
            app.ChannelNameLabel.Text = 'Channel Name';
            app.EnableLabel = uilabel(app.ChannelsPanel);
            app.EnableLabel.Text = 'Enable';
            app.chanName_1 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_1.Value = 'ch1';
            app.chanOn_1 = uiswitch(app.ChannelsPanel, 'toggle');
            app.chanOn_2 = uiswitch(app.ChannelsPanel, 'toggle');
            app.chanOn_3 = uiswitch(app.ChannelsPanel, 'toggle');
            app.chanOn_4 = uiswitch(app.ChannelsPanel, 'toggle');
            app.chanOn_5 = uiswitch(app.ChannelsPanel, 'toggle');
            app.chanOn_6 = uiswitch(app.ChannelsPanel, 'toggle');
            app.chanOn_7 = uiswitch(app.ChannelsPanel, 'toggle');
            app.chanOn_8 = uiswitch(app.ChannelsPanel, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger_triggered

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end